classdef SG__app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        TabGroup                     matlab.ui.container.TabGroup
        SetupTab                     matlab.ui.container.Tab
        TransferFunctionButtonGroup  matlab.ui.container.ButtonGroup
        H_i1Button                   matlab.ui.control.ToggleButton
        H_14Button                   matlab.ui.control.ToggleButton
        H_13Button                   matlab.ui.control.ToggleButton
        H_12Button                   matlab.ui.control.ToggleButton
        MeasureButton                matlab.ui.control.Button
        ComputeCorrectionTFButton    matlab.ui.control.Button
        ConnectButton                matlab.ui.control.Button
        SmatrixTab                   matlab.ui.container.Tab
        MeasureButton_2              matlab.ui.control.Button
        LoadTypeButtonGroup          matlab.ui.container.ButtonGroup
        H_bButton                    matlab.ui.control.ToggleButton
        H_aButton                    matlab.ui.control.ToggleButton
        ABORTButton                  matlab.ui.control.Button
        RightPanel                   matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        Lamp                         matlab.ui.control.Lamp
        EditField                    matlab.ui.control.EditField
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            addpath('__fun');
            p = param_struct(); % is a structure with all of the parameters
            tg = slrealtime(p.tg_model);
            
            %% PLOT FIRST CURVE
            cla(app.UIAxes);
            try
                load('C:/Speedgoat/temp/H12.mat');
                   plot(app.UIAxes,p.freq,abs(H12));
            catch 
                pause(0.01);
            end
             
            %% CHECK IF CONNECTED
            try
                   app.Lamp.Enable = 'off';
                   tg.connect;
                   pause(0.01);
                   tg.isConnected == 1; %#ok<EQEFF> 
                   pause(0.01);
                   app.EditField.Value = "Speedgoat connected."; 
                   pause(0.01);
                   app.Lamp.Enable = 'on';
                   pause(0.01);
                   
            catch 
                   app.EditField.Value = "Speedgoat disconnected.";
                   pause(0.01)
            end
        end

        % Button pushed function: MeasureButton
        function MeasureButtonPushed(app, event)
            addpath('__fun');
            p = param_struct(); % is a structure with all of the parameter
            Data = SG__measure();
                
            app.EditField.Value = "Measurement in progress..."; %%% VERBOSE
            
            %%% DEACTIVATE BUTTONS
            set(app.MeasureButton,'Enable','off')
            set(app.ComputeCorrectionTFButton,'Enable','off')

            if isfolder('C:/Speedgoat/temp') == 0
                mkdir C:/Speedgoat/temp %%% saves temp data on the local drive (for speed)
            end

            
            if app.H_12Button.Value
                
                %Data = SG__measure();
                
                app.EditField.Value = "Computing Transfer function..."; pause(0.01);
                
                
                tic
                H12 = [];
                [H12,F] = tfestimate(Data(:,2),Data(:,1),p.wind,[],p.freq,p.fs_acq);% zero padding?
                toc
              
                
                app.EditField.Value = "Saving data to file..."; pause(0.01);
                save( 'C:/Speedgoat/temp/H12.mat','H12');
                
                plot(app.UIAxes,F,abs(H12))
                
            elseif app.H_13Button.Value
                %Data = SG__measure();
                
                app.EditField.Value = "Computing Transfer function..."; pause(0.01);
                
                tic
                H13 = [];
                [H13,F] = tfestimate(Data(:,3),Data(:,1),p.wind,[],p.freq,p.fs_acq);
                toc

                app.EditField.Value = "Saving data to file..."; pause(0.01);
                save( 'C:/Speedgoat/temp/H13.mat','H13');
                
                plot(app.UIAxes,F,abs(H13))
                
            elseif app.H_14Button.Value 
                %Data = SG__measure();
                
                app.EditField.Value = "Computing Transfer function...";  pause(0.01);
                
                tic
                H14 = [];
                [H14,F] = tfestimate(Data(:,4),Data(:,1),p.wind,[],p.freq,p.fs_acq);
                toc
          

                app.EditField.Value = "Saving data to file..."; pause(0.01);
                save( 'C:/Speedgoat/temp/H14.mat','H14');
                
                plot(app.UIAxes,F,abs(H14));
                
            elseif app.H_i1Button.Value 
                %Data = SG__measure();

                app.EditField.Value = "Computing Transfer function...";  pause(0.01);
                
                tic
                H21 = [];
                H31 = [];
                H41 = [];
                
                [H21,F] = tfestimate(Data(:,1),Data(:,2),p.wind,[],p.freq,p.fs_acq); %%%% INVERSE???? no - this is NOT the cross signal as in the pulse see 20220701__MEASUREMENTS
                [H31,F] = tfestimate(Data(:,1),Data(:,3),p.wind,[],p.freq,p.fs_acq);
                [H41,F] = tfestimate(Data(:,1),Data(:,4),p.wind,[],p.freq,p.fs_acq);
                toc   
             
                
                app.EditField.Value = "Saving data to file..."; pause(0.01);
                save( 'C:/Speedgoat/temp/H21.mat','H21');
                save( 'C:/Speedgoat/temp/H31.mat','H31');
                save( 'C:/Speedgoat/temp/H41.mat','H41');
                
                %Also save calibration data to smatrix file
                mkdir __data
                
                H21_a = H21;
                H31_a = H31;
                H41_a = H41;
                
                save( './__data/H21_a.mat','H21_a');
                save( './__data/H31_a.mat','H31_a');
                save( './__data/H41_a.mat','H41_a');
                
                plot(app.UIAxes,F,abs(H21));
                hold(app.UIAxes,'on')
                plot(app.UIAxes,F,abs(H31));
                plot(app.UIAxes,F,abs(H41));
                hold(app.UIAxes,'off');

            end
         
         
        app.EditField.Value =  "Data saved."; pause(0.01); %%% VERBOSE
        
        %%% BUTTON ON
        set(app.MeasureButton,'Enable','on')
        set(app.ComputeCorrectionTFButton,'Enable','on')
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            addpath('__fun');
            p = param_struct(); % is a structure with all of the parameter
            tg = slrealtime(p.tg_model);

            tg.connect;

            if tg.isConnected == 1
                app.Lamp.Enable = 'off';
                pause(0.01);
                set(app.ConnectButton,'Enable','off');
                pause(0.01);
                app.EditField.Value = "Connection estabished."; 
                pause(0.01);
                app.EditField.Value = "Building target..."; 
                pause(0.01);
                %c0=0.1; WTF
                SG__build;
                pause(0.01);
                app.EditField.Value = "System ready."; 
                app.Lamp.Enable = 'on';
                pause(0.01);
                
            else
                app.Lamp.Enable = 'off';
                app.EditField.Value = "No connection. Try again."; 
            end
            set(app.ConnectButton,'Enable','on'); %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end

        % Button pushed function: ComputeCorrectionTFButton
        function ComputeCorrectionTFButtonPushed(app, event)
            set(app.ComputeCorrectionTFButton,'Enable','off') 
        
 
            %%% INPUT CALIBRATION DATA 
            try
                app.EditField.Value = "Saving calibration data..."; %%% VERBOSE
                pause(0.01)
 
                load('C:/Speedgoat/temp/H12.mat');
                load('C:/Speedgoat/temp/H13.mat');
                load('C:/Speedgoat/temp/H14.mat');
                
                load('C:/Speedgoat/temp/H21.mat');
                load('C:/Speedgoat/temp/H31.mat');
                load('C:/Speedgoat/temp/H41.mat');
               
                H21_corr = sqrt(H12./H21); %%%!!!!
                H31_corr = sqrt(H13./H31);
                H41_corr = sqrt(H14./H41);
              
                if isfolder ('__data') == 0;
                    mkdir __data %%% ON SERVER
                end

                save( './__data/H21_corr.mat','H21_corr');
                save( './__data/H31_corr.mat','H31_corr');
                save( './__data/H41_corr.mat','H41_corr');
   
                app.EditField.Value = "Calibration data saved."; %%% VERBOSE
                pause(0.01)
            catch
                app.EditField.Value = " "; 
                f = errordlg('Calibration file(s) not found.','File Error');
            end
            
            set(app.ComputeCorrectionTFButton,'Enable','on')  ; 

        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end

        % Selection changed function: TransferFunctionButtonGroup
        function TranferFunctionButtonGroupSelectionChanged(app, event)
            addpath('__fun');
            p = param_struct(); % is a structure with all of the parameter

            try 
                app.EditField.Value = "Plotting..."
                pause(0.01)
                cla(app.UIAxes);

                if app.H_12Button.Value
                    load('C:/Speedgoat/temp/H12.mat')
                    plot(app.UIAxes,p.freq,abs(H12));
                    
                elseif app.H_13Button.Value
                    load('C:/Speedgoat/temp/H13.mat')
                    plot(app.UIAxes,p.freq,abs(H13));
                    
                elseif app.H_14Button.Value
                    load('C:/Speedgoat/temp/H14.mat')
                    plot(app.UIAxes,p.freq,abs(H14));
                    
                elseif app.H_i1Button.Value
                    load('C:/Speedgoat/temp/H21.mat')
                    load('C:/Speedgoat/temp/H31.mat')
                    load('C:/Speedgoat/temp/H41.mat')
                    
                    plot(app.UIAxes,p.freq,abs(H21));
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,p.freq,abs(H31));
                    plot(app.UIAxes,p.freq,abs(H41));
                    hold(app.UIAxes,'off');
                end
                app.EditField.Value = ""
            catch
                app.EditField.Value = "Calibration file(s) not found."

            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {479, 479};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: ABORTButton
        function ABORTButtonPushed(app, event)
            addpath('__fun');
            p = param_struct(); % is a structure with all of the parameter
            tg = slrealtime(p.tg_model);
            
            %sc_list = tg.getscope; % all the scopes
            %sc = sc_list([sc_list.ScopeId] == 2); % scope 2 record
            
            tg.stop;% stops target
            
    
            pause(0.01);
            app.EditField.Value =  "Measurement aborted." %%% VERBOSE
            pause(0.01);
                
            %%% BUTTON ON
            set(app.ConnectButton,'Enable','on')
            set(app.MeasureButton,'Enable','on')
            set(app.MeasureButton_2,'Enable','on')
            set(app.ComputeCorrectionTFButton,'Enable','on')
        end

        % Callback function
        function SwitchValueChanged(app, event)
           
        end

        % Selection changed function: LoadTypeButtonGroup
        function LoadTypeButtonGroupSelectionChanged(app, event)
            addpath('__fun');
            p = param_struct(); % is a structure with all of the parameter
            try 
                app.EditField.Value = "Plotting..."
                pause(0.01)
                cla(app.UIAxes);
                if app.H_aButton.Value
                    load('./__data/H21_a.mat')
                    load('./__data/H31_a.mat')
                    load('./__data/H41_a.mat')
                    
                    plot(app.UIAxes,p.freq,abs(H21_a));
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,p.freq,abs(H31_a));
                    plot(app.UIAxes,p.freq,abs(H41_a));
                    hold(app.UIAxes,'off');
                    
                elseif app.H_bButton.Value
                    load('./__data/H21_b.mat')
                    load('./__data/H31_b.mat')
                    load('./__data/H41_b.mat')
                    
                    plot(app.UIAxes,p.freq,abs(H21_b));
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,p.freq,abs(H31_b));
                    plot(app.UIAxes,p.freq,abs(H41_b));
                    hold(app.UIAxes,'off');
                    
                end
                app.EditField.Value = ""
            catch
                app.EditField.Value = "Calibration file(s) not found."

            end
            
        end

        % Button pushed function: MeasureButton_2
        function MeasureButton_2Pushed(app, event)
            selectedButton = app.LoadTypeButtonGroup.SelectedObject;
            if isfolder('__data') == 0;
                mkdir __data;
            end
            p = param_struct(); % is a structure with all of the parameter
            
          
             %%% BUTTON OFF
            set(app.MeasureButton_2,'Enable','off')
         
            if selectedButton == app.H_aButton
                if isfile("./__data/H21_a.mat") 
                    answer = questdlg('Data files are already loaded. Would you like to overwrite them?','Warning','Yes','Cancel','Cancel');
                    % Handle response
                    switch answer
                        case 'Yes'
                            %%%
                        case 'Cancel'
                            set(app.MeasureButton_2,'Enable','on'); pause(0.01);
                            return
                    end
                end
                
                app.EditField.Value = "Measuring...";  pause(0.01);
                Data = SG__measure();
        
                app.EditField.Value = "Computing Transfer function...";  pause(0.01);

                H21_a = [];               
                H31_a = [];
                H41_a = [];
                
                C21_a = [];% coherence 
                C31_a = [];% coherence
                C41_a = [];% coherence  

                [H21_a,F] = tfestimate(Data(:,1),Data(:,2),p.wind,[],p.freq,p.fs_acq);
                [H31_a,F] = tfestimate(Data(:,1),Data(:,3),p.wind,[],p.freq,p.fs_acq);
                [H41_a,F] = tfestimate(Data(:,1),Data(:,4),p.wind,[],p.freq,p.fs_acq);

                [C21_a,F] = mscohere(Data(:,1),Data(:,2),p.wind,[],p.freq,p.fs_acq);
                [C31_a,F] = mscohere(Data(:,1),Data(:,3),p.wind,[],p.freq,p.fs_acq);
                [C41_a,F] = mscohere(Data(:,1),Data(:,4),p.wind,[],p.freq,p.fs_acq);
                
                save( './__data/H21_a.mat','H21_a');
                save( './__data/H31_a.mat','H31_a');
                save( './__data/H41_a.mat','H41_a');

                save( './__data/C21_a.mat','C21_a');
                save( './__data/C31_a.mat','C31_a');
                save( './__data/C41_a.mat','C41_a');
                
                plot(app.UIAxes,F,abs(H21_a));
                hold(app.UIAxes,'on')
                plot(app.UIAxes,F,abs(H31_a));
                plot(app.UIAxes,F,abs(H41_a));
                hold(app.UIAxes,'off');


            else          
                if isfile("./__data/H21_b.mat")
                    answer = questdlg('Data files are already loaded. Would you like to overwrite them?','Warning','Yes','Cancel','Cancel');
                    % Handle response
                    switch answer
                        case 'Yes'
                            %%%
                        case 'Cancel'
                            set(app.MeasureButton_2,'Enable','on'); pause(0.01);
                            return
                    end
                end

                app.EditField.Value = "Measuring...";  pause(0.01);
                Data = SG__measure();

                app.EditField.Value = "Computing Transfer function...";  pause(0.01);

                H21_b = [];
                H31_b = [];
                H41_b = [];

                C21_b = [];% coherence 
                C31_b = [];% coherence
                C41_b = [];% coherence  
                
                [H21_b,F] = tfestimate(Data(:,1),Data(:,2),p.wind,[],p.freq,p.fs_acq);
                [H31_b,F] = tfestimate(Data(:,1),Data(:,3),p.wind,[],p.freq,p.fs_acq);
                [H41_b,F] = tfestimate(Data(:,1),Data(:,4),p.wind,[],p.freq,p.fs_acq);    

                [C21_b,F] = mscohere(Data(:,1),Data(:,2),p.wind,[],p.freq,p.fs_acq);
                [C31_b,F] = mscohere(Data(:,1),Data(:,3),p.wind,[],p.freq,p.fs_acq);
                [C41_b,F] = mscohere(Data(:,1),Data(:,4),p.wind,[],p.freq,p.fs_acq);
                
                save( './__data/H21_b.mat','H21_b');
                save( './__data/H31_b.mat','H31_b');
                save( './__data/H41_b.mat','H41_b');

                save( './__data/C21_b.mat','C21_b');
                save( './__data/C31_b.mat','C31_b');
                save( './__data/C41_b.mat','C41_b');
                

                plot(app.UIAxes,F,abs(H21_b));
                hold(app.UIAxes,'on')
                plot(app.UIAxes,F,abs(H31_b));
                plot(app.UIAxes,F,abs(H41_b));
                hold(app.UIAxes,'off');

            end

            app.EditField.Value = ""; pause(0.01);
            %%% BUTTON ON
            set(app.MeasureButton_2,'Enable','on'); pause(0.01);

        end

        % Callback function
        function TransferFunction3ButtonGroupSelectionChanged(app, event)
            selectedButton = app.TransferFunctionButtonGroup.SelectedObject;
            
        end

        % Size changed function: TransferFunctionButtonGroup
        function TransferFunctionButtonGroupSizeChanged(app, event)
            position = app.TransferFunctionButtonGroup.Position;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ABORTButton = uibutton(app.LeftPanel, 'push');
            app.ABORTButton.ButtonPushedFcn = createCallbackFcn(app, @ABORTButtonPushed, true);
            app.ABORTButton.Text = 'ABORT';
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.ConnectButton = uibutton(app.SetupTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect ';
            app.ComputeCorrectionTFButton = uibutton(app.SetupTab, 'push');
            app.ComputeCorrectionTFButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeCorrectionTFButtonPushed, true);
            app.ComputeCorrectionTFButton.Text = 'Compute Correction TF';
            app.MeasureButton = uibutton(app.SetupTab, 'push');
            app.MeasureButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureButtonPushed, true);
            app.MeasureButton.Text = 'Measure';
            app.TransferFunctionButtonGroup = uibuttongroup(app.SetupTab);
            app.TransferFunctionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TranferFunctionButtonGroupSelectionChanged, true);
            app.TransferFunctionButtonGroup.Title = 'Transfer Function';
            app.TransferFunctionButtonGroup.SizeChangedFcn = createCallbackFcn(app, @TransferFunctionButtonGroupSizeChanged, true);
            app.H_12Button = uitogglebutton(app.TransferFunctionButtonGroup);
            app.H_12Button.Text = 'H_12';
            app.H_12Button.Value = true;
            app.H_13Button = uitogglebutton(app.TransferFunctionButtonGroup);
            app.H_13Button.Text = 'H_13';
            app.H_14Button = uitogglebutton(app.TransferFunctionButtonGroup);
            app.H_14Button.Text = 'H_14';
            app.H_i1Button = uitogglebutton(app.TransferFunctionButtonGroup);
            app.H_i1Button.Text = 'H_i1';
            app.SmatrixTab = uitab(app.TabGroup);
            app.SmatrixTab.Title = 'S-matrix';
            app.LoadTypeButtonGroup = uibuttongroup(app.SmatrixTab);
            app.LoadTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LoadTypeButtonGroupSelectionChanged, true);
            app.LoadTypeButtonGroup.Title = 'Load Type';
            app.H_aButton = uitogglebutton(app.LoadTypeButtonGroup);
            app.H_aButton.Text = 'H_a';
            app.H_aButton.Value = true;
            app.H_bButton = uitogglebutton(app.LoadTypeButtonGroup);
            app.H_bButton.Text = 'H_b';
            app.MeasureButton_2 = uibutton(app.SmatrixTab, 'push');
            app.MeasureButton_2.ButtonPushedFcn = createCallbackFcn(app, @MeasureButton_2Pushed, true);
            app.MeasureButton_2.Text = 'Measure';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Transfer functions ')
            xlabel(app.UIAxes, 'Freq (Hz)')
            ylabel(app.UIAxes, 'TF')
            app.EditField = uieditfield(app.GridLayout2, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Editable = 'off';
            app.Lamp = uilamp(app.GridLayout2);
            app.Lamp.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SG__app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end