classdef SG__app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        TabGroup                   matlab.ui.container.TabGroup
        SetupTab                   matlab.ui.container.Tab
        SwitchMicsButtonGroup      matlab.ui.container.ButtonGroup
        NominalButton              matlab.ui.control.ToggleButton
        Button_3                   matlab.ui.control.ToggleButton
        Button_2                   matlab.ui.control.ToggleButton
        Button                     matlab.ui.control.ToggleButton
        MeasureButton              matlab.ui.control.Button
        ComputeCorrectionTFButton  matlab.ui.control.Button
        UploadButton               matlab.ui.control.Button
        SmatrixTab                 matlab.ui.container.Tab
        MeasureButton_2            matlab.ui.control.Button
        SourceButtonGroup          matlab.ui.container.ButtonGroup
        ABButton                   matlab.ui.control.ToggleButton
        BButton                    matlab.ui.control.ToggleButton
        AButton                    matlab.ui.control.ToggleButton
        SineTab                    matlab.ui.container.Tab
        SimTimesEditField          matlab.ui.control.NumericEditField
        SimTimesEditFieldLabel     matlab.ui.control.Label
        FrequencyHzEditField       matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel  matlab.ui.control.Label
        MeasureButton_3            matlab.ui.control.Button
        RightPanel                 matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        Lamp                       matlab.ui.control.Lamp
        EditField                  matlab.ui.control.EditField
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            addpath('__fun');
            p = param_struct(); % is a structure with all of the parameters
            tg = slrealtime(p.tg_model);
            
            %% PLOT FIRST CURVE
            cla(app.UIAxes);
            try
                load('C:/Speedgoat/temp/cal.mat');
                plot(app.UIAxes,p.freq,cal.C12);
%                 load('C:/Speedgoat/temp/C12.mat');
%                 plot(app.UIAxes,p.freq,C12);
            catch 
                pause(0.01);
            end
             
            %% CHECK IF CONNECTED
            try
                   app.Lamp.Enable = 'off';
                   tg.connect;
                   pause(0.01);
                   tg.isConnected == 1; %#ok<EQEFF> 
                   pause(0.01);
                   app.EditField.Value = "Speedgoat connected."; 
                   pause(0.01);
                   app.Lamp.Enable = 'on';
                   pause(0.01);
                   
            catch 
                   app.EditField.Value = "Speedgoat disconnected.";
                   pause(0.01)
            end
        end

        % Button pushed function: MeasureButton
        function MeasureButtonPushed(app, event)
            %%% DEACTIVATE BUTTONS
            set(app.MeasureButton,'Enable','off')
            set(app.ComputeCorrectionTFButton,'Enable','off')
            
            %%% SET PARAMETERS
            addpath('__fun');
            p = param_struct(); % is a structure with all of the parameter
            
            %%% MAKE TEMP DIRECTORY ON LOCAL DRIVE TO STORE RAW DATA
            if isfolder('C:/Speedgoat/temp') == 0
                mkdir C:/Speedgoat/temp %%% saves temp data on the local drive (for speed)
                cal = struct;
                save( 'C:/Speedgoat/temp/cal.mat','cal');
            elseif ~isfile('C:/Speedgoat/temp/cal.mat') 
                cal = struct;
                save( 'C:/Speedgoat/temp/cal.mat','cal');
            end
   

            %%% MEASURE
            dlg = uiprogressdlg(app.UIFigure, 'Indeterminate','on','Cancelable','on');
            dlg.Message = 'Measurement in progress...';
            %app.EditField.Value = "Measurement in progress..."; %%% VERBOSE
            raw_data_temp = SG__measure(p,dlg);
            
            %%% PROCESS DATA
            dlg.Message = 'Computing Coherence...';
            %app.EditField.Value = "Computing Coherence..."; pause(0.01);
            if app.Button.Value
                %app.EditField.Value = "Computing Transfer function..."; pause(0.01);

                %%% COMPUTE COHERENCE FUNCTION
                load( 'C:/Speedgoat/temp/cal.mat','cal');
                cal.C12 = [];
                tic;
                [cal.C12,F] = mscohere(raw_data_temp(:,2),raw_data_temp(:,1),p.wind,[],p.freq,p.fs_log);
                delta_t = toc; %%% delta_t is displayed at end

                %%% SAVE DATA
                app.EditField.Value = "Saving data to file..."; pause(0.01);
                cal.D12 = raw_data_temp; %[raw_data_temp(:,1),raw_data_temp(:,2)];

                save( 'C:/Speedgoat/temp/cal.mat','cal');%raw data
                %save( 'C:/Speedgoat/temp/data_12.mat','data_12');%raw data
                %save( 'C:/Speedgoat/temp/H12.mat','H12');%estimated transfer function
                %save( 'C:/Speedgoat/temp/C12.mat','C12');%estimated coherence function              
                plot(app.UIAxes,F,abs(cal.C12))

                
            elseif app.Button_2.Value
                %%% COMPUTE COHERENCE FUNCTION
                load( 'C:/Speedgoat/temp/cal.mat','cal');
                cal.C13 = [];
                tic;
                [cal.C13,F] = mscohere(raw_data_temp(:,3),raw_data_temp(:,1),p.wind,[],p.freq,p.fs_log);
                delta_t = toc; %%% delta_t is displayed at end

                %%% SAVE DATA
                dlg.Message = 'Saving data to file...';
                %app.EditField.Value = "Saving data to file..."; pause(0.01);
                cal.D13 = raw_data_temp;%[raw_data_temp(:,1),raw_data_temp(:,3)];
                save( 'C:/Speedgoat/temp/cal.mat','cal');%raw data
                %save( 'C:/Speedgoat/temp/data_13.mat','data_13');%raw data
                %save( 'C:/Speedgoat/temp/C13.mat','C13');%estimated coherence function              
                plot(app.UIAxes,F,abs(cal.C13))

            elseif app.Button_3.Value 
                %app.EditField.Value = "Computing app.MeasureButtonapp.MeasureButtonTransfer function...";  pause(0.01);
                
                %tic
                %H14 = [];
                %[H14,F] = tfestimate(data(:,4),data(:,1),p.wind,[],p.freq,p.fs_log);
                %toc
                
                %%% COMPUTE COHERENCE FUNCTION
                load( 'C:/Speedgoat/temp/cal.mat','cal');
                cal.C14 = [];
                tic;
                [cal.C14,F] = mscohere(raw_data_temp(:,4),raw_data_temp(:,1),p.wind,[],p.freq,p.fs_log);
                delta_t = toc; %%% delta_t is displayed at end

                %%% SAVE DATA
                dlg.Message = 'Saving data to file...';
                %app.EditField.Value = "Saving data to file..."; pause(0.01);
                cal.D14 = raw_data_temp;%[raw_data_temp(:,1),raw_data_temp(:,4)];
                save( 'C:/Speedgoat/temp/cal.mat','cal');%raw data
                %save( 'C:/Speedgoat/temp/data_14.mat','data_14');%raw data
                %save( 'C:/Speedgoat/temp/C14.mat','C14');%estimated coherence function              
                plot(app.UIAxes,F,abs(cal.C14))

            elseif app.NominalButton.Value 
                %app.EditField.Value = "Computing Transfer function...";  pause(0.01);
                
                %tic
                %H21 = [];
                %H31 = [];
                %H41 = [];
                %
                %[H21,F] = tfestimate(data(:,1),data(:,2),p.wind,[],p.freq,p.fs_log); %%%% INVERSE???? no - this is NOT the cross signal as in the pulse see 20220701__MEASUREMENTS
                %[H31,F] = tfestimate(data(:,1),data(:,3),p.wind,[],p.freq,p.fs_log);
                %[H41,F] = tfestimate(data(:,1),data(:,4),p.wind,[],p.freq,p.fs_log);
                %toc 
                %%% COMPUTE COHERENCE FUNCTION
                load( 'C:/Speedgoat/temp/cal.mat','cal');
                tic
                cal.C21 = [];
                cal.C31 = [];
                cal.C41 = [];

                [cal.C21,F] = mscohere(raw_data_temp(:,1),raw_data_temp(:,2),p.wind,[],p.freq,p.fs_log); 
                [cal.C31,F] = mscohere(raw_data_temp(:,1),raw_data_temp(:,3),p.wind,[],p.freq,p.fs_log);
                [cal.C41,F] = mscohere(raw_data_temp(:,1),raw_data_temp(:,4),p.wind,[],p.freq,p.fs_log);
                delta_t = toc;
                
                cal.Di1 = raw_data_temp;

                %cal.raw_21 = [raw_data_temp(:,1),raw_data_temp(:,2)];%%%% INVERSE???? no - this is NOT the cross signal as in the pulse see 20220701__MEASUREMENTS
                %cal.raw_31 = [raw_data_temp(:,1),raw_data_temp(:,3)];
                %cal.raw_41 = [raw_data_temp(:,1),raw_data_temp(:,4)];


                %%% SAVE DATA TO TEMP FILE
                dlg.Message = 'Saving data to file...';
                %app.EditField.Value = "Saving data to file..."; pause(0.01);
                save( 'C:/Speedgoat/temp/cal.mat','cal');

                %save( 'C:/Speedgoat/temp/data_21.mat','data_21');
                %save( 'C:/Speedgoat/temp/data_31.mat','data_31');
                %save( 'C:/Speedgoat/temp/data_41.mat','data_41');
%
                %save( 'C:/Speedgoat/temp/C21.mat','C21');%estimated coherence function  
                %save( 'C:/Speedgoat/temp/C31.mat','C31'); 
                %save( 'C:/Speedgoat/temp/C41.mat','C41'); 
                
                
                plot(app.UIAxes,F,abs(cal.C21));
                hold(app.UIAxes,'on')
                plot(app.UIAxes,F,abs(cal.C31));
                plot(app.UIAxes,F,abs(cal.C41));
                hold(app.UIAxes,'off');

            end
         
        dlg.close() 
        %app.EditField.Value =  "Data saved."; pause(0.5); %%% VERBOSE
        app.EditField.Value = strcat("Computation time = ",string(delta_t )," s"); pause(0.01);
        %%% BUTTON ON
        set(app.MeasureButton,'Enable','on')
        set(app.ComputeCorrectionTFButton,'Enable','on')
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            addpath('__fun');
            p = param_struct(); % is a structure with all of the parameter
            tg = slrealtime(p.tg_model);
            try
                dlg = uiprogressdlg(app.UIFigure, 'Indeterminate','on');
                dlg.Message = 'Connecting...';
                %app.EditField.Value = "Connecting..."; 
                pause(0.01);
                tg.connect;

                %if tg.isConnected == 1
                app.Lamp.Enable = 'off';
                pause(0.01);
                set(app.UploadButton,'Enable','off');
                pause(0.01);
                dlg.Message = 'Connection estabished.';
                %app.EditField.Value = "Connection estabished."; 
                pause(0.01);
                dlg.Message = 'Building target...';
                %app.EditField.Value = "Building target..."; 
                pause(0.01);
                %c0=0.1; WTF
                SG__build()
                pause(0.01);
                app.EditField.Value = "System ready."; 
                app.Lamp.Enable = 'on';
                pause(0.01);
%                 else
%                     app.Lamp.Enable = 'off';
%                     app.EditField.Value = "No connection. Try again."; 
                
            catch
                app.Lamp.Enable = 'off';
                app.EditField.Value = "Connection failed."; 
            end
            dlg.close()
            set(app.UploadButton,'Enable','on'); %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end

        % Button pushed function: ComputeCorrectionTFButton
        function ComputeCorrectionTFButtonPushed(app, event)
            
            set(app.ComputeCorrectionTFButton,'Enable','off') 
            addpath('__fun');
            p = param_struct(); % is a structure with all of the parameter
            
             %%% MAKE STRUCTURE TO BE SAVED IN LOCAL DIRECTORY ON SERVER
            if isfolder('./__data') == 0
                mkdir './__data' %%% saves temp data on the local directory
                cal = struct;
            elseif isfile('./__data/cal.mat') == 0
                cal = struct;
            end
 
            %%% INPUT CALIBRATION DATA 
            try
                dlg = uiprogressdlg(app.UIFigure, 'Indeterminate','on', 'Title', 'Correction transfer function.')
                dlg.Message = 'Loading data...';
                load('C:/Speedgoat/temp/cal.mat');                  
                %% TRANSFER FUNCTION CORRECTION FUNCTION
                dlg.Message = 'Computing transfer functions...';
                %app.EditField.Value = "Computing transfer functions...";pause(0.01) %%% VERBOSE
                tic;
                [cal.H12,F] = tfestimate(cal.D12(:,1),cal.D12(:,2),p.wind,[],p.freq,p.fs_log); 
                [cal.H13,F] = tfestimate(cal.D13(:,1),cal.D13(:,3),p.wind,[],p.freq,p.fs_log);
                [cal.H14,F] = tfestimate(cal.D14(:,1),cal.D14(:,4),p.wind,[],p.freq,p.fs_log);
                [cal.H21,F] = tfestimate(cal.Di1(:,1),cal.Di1(:,2),p.wind,[],p.freq,p.fs_log);%%%the subscript "a" is added here to such that the data can be directly processed in the Smatrix tab
                [cal.H31,F] = tfestimate(cal.Di1(:,1),cal.Di1(:,3),p.wind,[],p.freq,p.fs_log);
                [cal.H41,F] = tfestimate(cal.Di1(:,1),cal.Di1(:,4),p.wind,[],p.freq,p.fs_log);
                    
                %%% CORRECTION TRANSFERFUNCTION cor
                cal.H21_corr = sqrt(cal.H12.*cal.H21); %%%20221110--> changes from ./" to ".*" !!!! %correction
                cal.H31_corr = sqrt(cal.H13.*cal.H31);
                cal.H41_corr = sqrt(cal.H14.*cal.H41);
                
                
                %% SAVE TRANSFER FUNCTIONS AND CORRECTION OR NOMINAL SWEEP IN LOCAL FOLDER
                %%% FOR IMMEDIATE POST PROCESSING
                dlg.Message = 'Saving data...';
                %app.EditField.Value = "Saving data...";pause(0.01) %%% VERBOSE
                %raw_data_a = cal.Di1;
                
                processed_data_a.H21 = cal.H21;
                processed_data_a.H31 = cal.H31;
                processed_data_a.H41 = cal.H41;
                processed_data_a.C21 = cal.C21;
                processed_data_a.C31 = cal.C31;
                processed_data_a.C41 = cal.C41;

                %save( './__data/cal.mat','cal');
                save( sprintf('./__data/cal__%0.0f_%0.0f_%0.0fus.mat',p.freq_ini,p.freq_fin,p.ts_log/1e-6),'cal'); 
                %save( sprintf('./__data/cal__%0.0f_%0.0f_%0.0fus_%0.0f.mat', p.freq_ini, p.freq_fin, p.ts/1e-6, p.log_dec),'cal'); 
                save( 'C:/Speedgoat/temp/processed_data_a.mat','processed_data_a');
                %save( './__data/raw_data_a.mat','raw_data_a');


                %save( './__data/freq.mat','freq');


                %fprintf('2\n')
                %%% SAVE WITH SUBSCRIPT "a"
                % added here to such that the data can be directly processed in the Smatrix tab
               
%                 H21_a = H21;
%                 H31_a = H31;
%                 H41_a = H41;
% 
%                 C21_a = C21;
%                 C31_a = C31;
%                 C41_a = C41;
% 
%                 data_21_a = data_21;
%                 data_31_a = data_31;
%                 data_41_a = data_41;
% 
%                 save( 'C:/Speedgoat/temp/H21_a.mat','H21_a');
%                 save( 'C:/Speedgoat/temp/H31_a.mat','H31_a');
%                 save( 'C:/Speedgoat/temp/H41_a.mat','H41_a'); 
% 
%                 save( 'C:/Speedgoat/temp/C21_a.mat','C21_a');
%                 save( 'C:/Speedgoat/temp/C31_a.mat','C31_a');
%                 save( 'C:/Speedgoat/temp/C41_a.mat','C41_a'); 
% 
%                 save( 'C:/Speedgoat/temp/data_21_a.mat','data_21_a');
%                 save( 'C:/Speedgoat/temp/data_31_a.mat','data_31_a');
%                 save( 'C:/Speedgoat/temp/data_41_a.mat','data_41_a');

                delta_t = toc; %%% delta_t is displayed at end
                
                %app.EditField.Value = "Estimated transfer functions and calibration data saved."; %%% VERBOSE
                pause(0.5);
                app.EditField.Value = strcat("Computation time = ",string(delta_t )," s"); pause(0.01);
            catch
                app.EditField.Value = " "; 
                f = errordlg('Data file(s) not found.','File Error');
            end
            
            set(app.ComputeCorrectionTFButton,'Enable','on')  ; 
            dlg.close();

        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end

        % Selection changed function: SwitchMicsButtonGroup
        function TranferFunctionButtonGroupSelectionChanged(app, event)
            %%% 
            addpath('__fun');
            p = param_struct(); % is a structure with all of the parameter
            
            try 
                cla(app.UIAxes);
                load('C:/Speedgoat/temp/cal.mat')

                if app.Button.Value
                    %load('C:/Speedgoat/temp/C12.mat')
                    plot(app.UIAxes,p.freq,cal.C12);
                    
                elseif app.Button_2.Value
                    %load('C:/Speedgoat/temp/C13.mat')
                    plot(app.UIAxes,p.freq,cal.C13);
                    
                elseif app.Button_3.Value
                    %load('C:/Speedgoat/temp/C14.mat')
                    plot(app.UIAxes,p.freq,cal.C14);
                    
                elseif app.NominalButton.Value
                    %load('C:/Speedgoat/temp/C21.mat')
                    %load('C:/Speedgoat/temp/C31.mat')
                    %load('C:/Speedgoat/temp/C41.mat')

                    plot(app.UIAxes,p.freq,cal.C21);
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,p.freq,cal.C31);
                    plot(app.UIAxes,p.freq,cal.C41);
                    hold(app.UIAxes,'off');
                end
                app.EditField.Value = ""
            catch
                app.EditField.Value = "Calibration file(s) not found."

            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {479, 479};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function
        function ABORTButtonPushed(app, event)
            addpath('__fun');
            p = param_struct(); % is a structure with all of the parameter
            tg = slrealtime(p.tg_model);
            
            tg.stop;% stops target

            pause(0.01);
            app.EditField.Value =  "Measurement aborted." %%% VERBOSE
            pause(0.01);
                
            %%% BUTTON ON
            set(app.UploadButton,'Enable','on')
            set(app.MeasureButton,'Enable','on')
            set(app.MeasureButton_2,'Enable','on')
            set(app.ComputeCorrectionTFButton,'Enable','on')
        end

        % Callback function
        function SwitchValueChanged(app, event)
           
        end

        % Selection changed function: SourceButtonGroup
        function SourceButtonGroupSelectionChanged(app, event)
            addpath('__fun');
            p = param_struct(); % is a structure with all of the parameter
            try 
                pause(0.01)
                cla(app.UIAxes);
                if app.AButton.Value
                    load('C:/Speedgoat/temp/processed_data_a.mat');
                    %load('C:/Speedgoat/temp/C21_a.mat')
                    %load('C:/Speedgoat/temp/C31_a.mat')
                    %load('C:/Speedgoat/temp/C41_a.mat')
                    
                    plot(app.UIAxes,processed_data_a.F,abs(processed_data_a.C21));
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,processed_data_a.F,abs(processed_data_a.C31));
                    plot(app.UIAxes,processed_data_a.F,abs(processed_data_a.C41));
                    hold(app.UIAxes,'off');
                    
                elseif app.BButton.Value
                    load('C:/Speedgoat/temp/processed_data_b.mat');
                    %load('C:/Speedgoat/temp/C21_b.mat')
                    %load('C:/Speedgoat/temp/C31_b.mat')
                    %load('C:/Speedgoat/temp/C41_b.mat')
                    plot(app.UIAxes,processed_data_b.F,abs(processed_data_b.C21));
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,processed_data_b.F,abs(processed_data_b.C31));
                    plot(app.UIAxes,processed_data_b.F,abs(processed_data_b.C41));
                    hold(app.UIAxes,'off');
                    
                end
                app.EditField.Value = ""
            catch
                app.EditField.Value = "Data file(s) not found."

            end
        end

        % Button pushed function: MeasureButton_2
        function MeasureButton_2Pushed(app, event)
            selectedButton = app.SourceButtonGroup.SelectedObject;
            try
                if isfolder('__data') == 0;
                    mkdir __data;
                end
                p = param_struct(); % is a structure with all of the parameter
                    
%                 if ~isstruct(data_struct)
%                     data_struct = struct;
%                     fprintf("th")
%                 end
                addpath('__fun');
              
                 %%% BUTTON OFF
                %set(app.MeasureButton_2,'Enable','off')
                
                %% PRESS A BUTTON
                if selectedButton == app.AButton 
                    if isfile("C:/Speedgoat/temp/processed_data_a.mat") 
                        answer = questdlg('Data files are already loaded. Would you like to overwrite them?','Warning','Yes','Cancel','Cancel');
                        % Handle response
                        switch answer
                            case 'Yes'
                                %%%
                            case 'Cancel'
                                set(app.MeasureButton_2,'Enable','on'); pause(0.01);
                                return
                        end
                    end
                    dlg = uiprogressdlg(app.UIFigure, 'Indeterminate','on','Cancelable','on');
                    dlg.Title =  'Source a';
                    dlg.Message = 'Measuring...';
                    %app.EditField.Value = "Measuring...";  pause(0.01);
                    p.src_select = 1;
                    raw_data = SG__measure(p,dlg);
                    
                    dlg.Message = 'Computing coherence and transfer function...';
                    %app.EditField.Value = "Computing coherence and transfer function...";  pause(0.01);
                    
                    %%%%%%%%%%%
                    processed_data_a = SG__data_process(raw_data); %%%%%%%%%%%
                    
                    dlg.Message = 'Saving data...';
                    %app.EditField.Value = "Saving data...";  pause(0.01);
                    save('C:/Speedgoat/temp/processed_data_a.mat','processed_data_a');
                    %SG__data_save(processed_data ,"a")
                    
                    dlg.Message = 'Plotting...';
                    %app.EditField.Value = "Plotting...";  pause(0.01);
                    
                    plot(app.UIAxes,processed_data_a.F,abs(processed_data_a.C21));
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,processed_data_a.F,abs(processed_data_a.C31));
                    plot(app.UIAxes,processed_data_a.F,abs(processed_data_a.C41));
                    hold(app.UIAxes,'off');
                    app.EditField.Value = "Done.";  pause(0.01);


                %% PRESS B BUTTON
                elseif selectedButton == app.BButton          
                    if isfile("./__data/processed_data.mat")
                        answer = questdlg('data files are already loaded. Would you like to overwrite them?','Warning','Yes','Cancel','Cancel');
                        % Handle response
                        switch answer
                            case 'Yes'
                                %%%
                            case 'Cancel'
                                set(app.MeasureButton_2,'Enable','on'); pause(0.01);
                                return
                        end
                    end
                    dlg = uiprogressdlg(app.UIFigure, 'Indeterminate','on','Cancelable','on');
                    dlg.Title =  'Source b';
                    dlg.Message = 'Measuring...';
                    %app.EditField.Value = "Measuring...";  pause(0.01);

                    p.src_select = 2; 
                    raw_data = SG__measure(p,dlg);
    
                    dlg.Message = 'Computing coherence and transfer function...';
                    %app.EditField.Value = "Computing coherence and transfer function...";  pause(0.01);

                    %%%%%%%%%%%
                    processed_data_b = SG__data_process(raw_data); %%%%%%%%%%%
                    
                    dlg.Message = 'Saving data...';
                    save('C:/Speedgoat/temp/processed_data_b.mat','processed_data_b');
                    %SG__data_save(processed_data ,"b");
                    app.EditField.Value = "Done.";  pause(0.01);
    
                    plot(app.UIAxes,processed_data_b.F,abs(processed_data_b.C21));
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,processed_data_b.F,abs(processed_data_b.C31));
                    plot(app.UIAxes,processed_data_b.F,abs(processed_data_b.C41));
                    hold(app.UIAxes,'off');
    

                %% PRESS A & B  BUTTON
                elseif selectedButton == app.ABButton 
                    if isfile("C:/Speedgoat/temp/processed_data_a.mat")&&isfile("C:/Speedgoat/temp/processed_data_b.mat")  
                        answer = questdlg('Data files are already loaded. Would you like to overwrite them?','Warning','Yes','Cancel','Cancel');
                        % Handle response
                        switch answer
                            case 'Yes'
                                %%%
                            case 'Cancel'
                                set(app.MeasureButton_2,'Enable','on'); pause(0.01);
                                return
                        end
                    end

                    %%% A
                    dlg = uiprogressdlg(app.UIFigure, 'Indeterminate','on','Cancelable','on');
                    dlg.Title =  'Source a';
                    dlg.Message = 'Measuring...';
                    p.src_select = 1;
                    raw_data = SG__measure(p,dlg);
                    
                    dlg.Message = 'Computing coherence and transfer function...';

                    processed_data_a = SG__data_process(raw_data); 
                    
                    dlg.Message = 'Saving data...';
                    %app.EditField.Value = "Saving data...";  pause(0.01);
                    save('C:/Speedgoat/temp/processed_data_a.mat','processed_data_a');
                    %SG__data_save(processed_data ,"a")
                    
                    dlg.Message = 'Plotting...';
                    %app.EditField.Value = "Plotting...";  pause(0.01);
                    
                    plot(app.UIAxes,processed_data_a.F,abs(processed_data_a.C21));
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,processed_data_a.F,abs(processed_data_a.C31));
                    plot(app.UIAxes,processed_data_a.F,abs(processed_data_a.C41));
                    hold(app.UIAxes,'off');
                    app.EditField.Value = "Done.";  pause(0.01);

                    %%% B
                    dlg = uiprogressdlg(app.UIFigure, 'Indeterminate','on','Cancelable','on');
                    dlg.Title =  'Source b';
                    dlg.Message = 'Measuring...';
                    %app.EditField.Value = "Measuring...";  pause(0.01);

                    p.src_select = 2; 
                    raw_data = SG__measure(p,dlg);
    
                    dlg.Message = 'Computing coherence and transfer function...';

                    processed_data_b = SG__data_process(raw_data); 
                    
                    dlg.Message = 'Saving data...';
                    save('C:/Speedgoat/temp/processed_data_b.mat','processed_data_b');
                    %SG__data_save(processed_data ,"b");
                    app.EditField.Value = "Done.";  pause(0.01);
    
                    plot(app.UIAxes,processed_data_b.F,abs(processed_data_b.C21));
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,processed_data_b.F,abs(processed_data_b.C31));
                    plot(app.UIAxes,processed_data_b.F,abs(processed_data_b.C41));
                    hold(app.UIAxes,'off');

                end 
                %app.EditField.Value = ""; pause(0.01);
                %%% BUTTON ON
                %set(app.MeasureButton_2,'Enable','on'); pause(0.01);
            end
        dlg.close()
        %set(app.MeasureButton_2,'Enable','on')
        end

        % Callback function
        function TransferFunction3ButtonGroupSelectionChanged(app, event)
            selectedButton = app.SwitchMicsButtonGroup.SelectedObject;
            
        end

        % Size changed function: SwitchMicsButtonGroup
        function SwitchMicsButtonGroupSizeChanged(app, event)
            position = app.SwitchMicsButtonGroup.Position;
            
        end

        % Button pushed function: MeasureButton_3
        function MeasureButton_3Pushed(app, event)
            try 
                if isfolder('__data') == 0;
                    mkdir __data;
                end
                p = param_struct(); % is a structure with all of the parameter
                addpath('__fun');
                dlg = uiprogressdlg(app.UIFigure, 'Indeterminate','on','Cancelable','on');
              
                 %%% BUTTON OFF
                %set(app.MeasureButton_2,'Enable','off')
            end
        dlg.close()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.UploadButton = uibutton(app.SetupTab, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.ComputeCorrectionTFButton = uibutton(app.SetupTab, 'push');
            app.ComputeCorrectionTFButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeCorrectionTFButtonPushed, true);
            app.ComputeCorrectionTFButton.Text = 'Compute Correction TF';
            app.MeasureButton = uibutton(app.SetupTab, 'push');
            app.MeasureButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureButtonPushed, true);
            app.MeasureButton.Text = 'Measure';
            app.SwitchMicsButtonGroup = uibuttongroup(app.SetupTab);
            app.SwitchMicsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TranferFunctionButtonGroupSelectionChanged, true);
            app.SwitchMicsButtonGroup.Title = 'Switch Mics.';
            app.SwitchMicsButtonGroup.SizeChangedFcn = createCallbackFcn(app, @SwitchMicsButtonGroupSizeChanged, true);
            app.Button = uitogglebutton(app.SwitchMicsButtonGroup);
            app.Button.Text = '1 & 2';
            app.Button.Value = true;
            app.Button_2 = uitogglebutton(app.SwitchMicsButtonGroup);
            app.Button_2.Text = '1 & 3';
            app.Button_3 = uitogglebutton(app.SwitchMicsButtonGroup);
            app.Button_3.Text = '1 & 4';
            app.NominalButton = uitogglebutton(app.SwitchMicsButtonGroup);
            app.NominalButton.Text = 'Nominal';
            app.SmatrixTab = uitab(app.TabGroup);
            app.SmatrixTab.Title = 'S-matrix';
            app.SourceButtonGroup = uibuttongroup(app.SmatrixTab);
            app.SourceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SourceButtonGroupSelectionChanged, true);
            app.SourceButtonGroup.Title = 'Source';
            app.AButton = uitogglebutton(app.SourceButtonGroup);
            app.AButton.Text = 'A';
            app.AButton.Value = true;
            app.BButton = uitogglebutton(app.SourceButtonGroup);
            app.BButton.Text = 'B';
            app.ABButton = uitogglebutton(app.SourceButtonGroup);
            app.ABButton.Text = 'A & B';
            app.MeasureButton_2 = uibutton(app.SmatrixTab, 'push');
            app.MeasureButton_2.ButtonPushedFcn = createCallbackFcn(app, @MeasureButton_2Pushed, true);
            app.MeasureButton_2.Text = 'Measure';
            app.SineTab = uitab(app.TabGroup);
            app.SineTab.Title = 'Sine';
            app.MeasureButton_3 = uibutton(app.SineTab, 'push');
            app.MeasureButton_3.ButtonPushedFcn = createCallbackFcn(app, @MeasureButton_3Pushed, true);
            app.MeasureButton_3.Text = 'Measure';
            app.FrequencyHzEditFieldLabel = uilabel(app.SineTab);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField = uieditfield(app.SineTab, 'numeric');
            app.FrequencyHzEditField.Limits = [0 40000];
            app.FrequencyHzEditField.Value = 500;
            app.SimTimesEditFieldLabel = uilabel(app.SineTab);
            app.SimTimesEditFieldLabel.Text = 'Sim. Time (s)';
            app.SimTimesEditField = uieditfield(app.SineTab, 'numeric');
            app.SimTimesEditField.Limits = [0 1000];
            app.SimTimesEditField.Value = 5;
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Signal')
            xlabel(app.UIAxes, 'Freq (Hz)')
            ylabel(app.UIAxes, 'a.u.')
            app.UIAxes.YLim = [0 1];
            app.EditField = uieditfield(app.GridLayout2, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Editable = 'off';
            app.Lamp = uilamp(app.GridLayout2);
            app.Lamp.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SG__app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end