classdef Exp_FF_AER < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes4                       matlab.ui.control.UIAxes
        RunButton                     matlab.ui.control.Button
        SamplefrequencyHzEditFieldLabel  matlab.ui.control.Label
        SamplefrequencyHzEditField    matlab.ui.control.NumericEditField
        UIAxes5                       matlab.ui.control.UIAxes
        UIAxes6                       matlab.ui.control.UIAxes
        ControlsystemPanel            matlab.ui.container.Panel
        mu_MEditFieldLabel            matlab.ui.control.Label
        mu_MEditField                 matlab.ui.control.NumericEditField
        mu_REditFieldLabel            matlab.ui.control.Label
        mu_REditField                 matlab.ui.control.NumericEditField
        mu_CEditFieldLabel            matlab.ui.control.Label
        mu_CEditField                 matlab.ui.control.NumericEditField
        GainVAEditFieldLabel          matlab.ui.control.Label
        GainVAEditField               matlab.ui.control.NumericEditField
        SourcePanel                   matlab.ui.container.Panel
        FrequencyrangeEditFieldLabel  matlab.ui.control.Label
        fromEditFieldLabel            matlab.ui.control.Label
        fromEditField                 matlab.ui.control.NumericEditField
        toEditFieldLabel              matlab.ui.control.Label
        toEditField                   matlab.ui.control.NumericEditField
        TimeintervalEditFieldLabel    matlab.ui.control.Label
        targettimesEditFieldLabel     matlab.ui.control.Label
        targettimesEditField          matlab.ui.control.NumericEditField
        SweeptimesEditFieldLabel      matlab.ui.control.Label
        SweeptimesEditField           matlab.ui.control.NumericEditField
        AmplitudeVLabel               matlab.ui.control.Label
        AmplitudeVEditField           matlab.ui.control.NumericEditField
        SensibilitiesPanel            matlab.ui.container.Panel
        SensibilityofmicrophoneEditFieldLabel  matlab.ui.control.Label
        SensibilityofvibrometerVmsEditFieldLabel  matlab.ui.control.Label
        SensibilityofvibrometerVmsEditField  matlab.ui.control.NumericEditField
        Gainofmic1EditFieldLabel      matlab.ui.control.Label
        Gainofmic1EditField           matlab.ui.control.NumericEditField
        NominalvalueEditFieldLabel    matlab.ui.control.Label
        NominalvalueEditField         matlab.ui.control.NumericEditField
        Gainofmic2EditFieldLabel      matlab.ui.control.Label
        Gainofmic2EditField           matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            Fe=app.SamplefrequencyHzEditField.Value;
            Amp=app.AmplitudeVEditField.Value;
            F_range=[app.fromEditField.Value, app.toEditField.Value];
            t_range=[app.targettimesEditField.Value, app.SweeptimesEditField.Value];
            sens_pf=(app.NominalvalueEditField.Value)/app.Gainofmic1EditField.Value;
            sens_pb=(app.NominalvalueEditField.Value)/app.Gainofmic2EditField.Value;
            sens_v=app.SensibilityofvibrometerVmsEditField.Value;
            Sensibilities=[sens_pf,sens_pb,sens_v];
            Control_para=[app.mu_MEditField.Value, app.mu_REditField.Value, app.mu_CEditField.Value, app.GainVAEditField.Value];
            
            [t, p, v, f, TF_P, TF_V, Zs, alpha] = Exp_FF_AER_SN5292_Function(Fe, Amp, F_range, t_range, Sensibilities, Control_para);
            
            MarkerSize=12;
            LineWidth=1;

            F_min=F_range(1);
            F_max=F_range(2);
            
            plot(app.UIAxes, t, p,'-','MarkerSize',MarkerSize,'LineWidth',LineWidth)
            xlim(app.UIAxes, [t(1), t(end)])
            ylim(app.UIAxes, [min(p), max(p)]*1.1)
            
            plot(app.UIAxes2, t, v,'-','MarkerSize',MarkerSize,'LineWidth',LineWidth)
            xlim(app.UIAxes2, [t(1), t(end)])
            ylim(app.UIAxes2, [min(v), max(v)]*1.1)
            
%             plot(app.UIAxes3, ff, abs(P(1:NFFT/2+1)),'-','MarkerSize',MarkerSize,'LineWidth',LineWidth)
            plot(app.UIAxes3, f, abs(TF_P),'-','MarkerSize',MarkerSize,'LineWidth',LineWidth)
            xlim(app.UIAxes3, [F_min, F_max])
            ylim(app.UIAxes3, [0, max(abs(TF_P))*1.1])
            
%             plot(app.UIAxes4,ff, abs(V(1:NFFT/2+1)),'-','MarkerSize',MarkerSize,'LineWidth',LineWidth)
            plot(app.UIAxes4,f, abs(TF_V),'-','MarkerSize',MarkerSize,'LineWidth',LineWidth)
            xlim(app.UIAxes4, [F_min, F_max])
            ylim(app.UIAxes4, [0, max(abs(TF_V))*1.1])
            
            plot(app.UIAxes5,f, abs(Zs),'-.','MarkerSize',MarkerSize,'LineWidth',LineWidth)
            xlim(app.UIAxes5,[F_min,F_max])
            ylim(app.UIAxes5,[0,8000])
            
            plot(app.UIAxes6,f,alpha,'-.','MarkerSize',MarkerSize,'LineWidth',LineWidth)
            xlim(app.UIAxes6,[F_min,F_max])
            ylim(app.UIAxes6,[0,1])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Pressure')
            xlabel(app.UIAxes, 't (s)')
            ylabel(app.UIAxes, 'Amp. (Pa)')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Velocity')
            xlabel(app.UIAxes2, 't (s)')
            ylabel(app.UIAxes2, 'Amp. (m/s)')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'TF(Pressure, Source)')
            xlabel(app.UIAxes3, 'f (Hz)')
            ylabel(app.UIAxes3, 'Pa/V')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'TF(Velocity, Source))')
            xlabel(app.UIAxes4, 'f (Hz)')
            ylabel(app.UIAxes4, '(m/s)/V')
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SamplefrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.SamplefrequencyHzEditFieldLabel.Text = 'Sample frequency (Hz)';
            app.SamplefrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplefrequencyHzEditField.Limits = [0 50000];
            app.SamplefrequencyHzEditField.Value = 40000;
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Impedance')
            xlabel(app.UIAxes5, 'f (Hz)')
            ylabel(app.UIAxes5, 'Abs. (Pa(m/s))')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Absorption Coefficient')
            xlabel(app.UIAxes6, 'f (Hz)')
            ylabel(app.UIAxes6, 'Magnitude')
            app.ControlsystemPanel = uipanel(app.UIFigure);
            app.ControlsystemPanel.Title = 'Control system:';
            app.mu_MEditFieldLabel = uilabel(app.ControlsystemPanel);
            app.mu_MEditFieldLabel.Text = 'mu_M';
            app.mu_MEditField = uieditfield(app.ControlsystemPanel, 'numeric');
            app.mu_MEditField.Value = 1;
            app.mu_REditFieldLabel = uilabel(app.ControlsystemPanel);
            app.mu_REditFieldLabel.Text = 'mu_R';
            app.mu_REditField = uieditfield(app.ControlsystemPanel, 'numeric');
            app.mu_REditField.Value = 1;
            app.mu_CEditFieldLabel = uilabel(app.ControlsystemPanel);
            app.mu_CEditFieldLabel.Text = 'mu_C';
            app.mu_CEditField = uieditfield(app.ControlsystemPanel, 'numeric');
            app.mu_CEditField.Value = 2;
            app.GainVAEditFieldLabel = uilabel(app.ControlsystemPanel);
            app.GainVAEditFieldLabel.Text = 'Gain (V/A)';
            app.GainVAEditField = uieditfield(app.ControlsystemPanel, 'numeric');
            app.GainVAEditField.Value = 103.8;
            app.SourcePanel = uipanel(app.UIFigure);
            app.SourcePanel.Title = 'Source:';
            app.FrequencyrangeEditFieldLabel = uilabel(app.SourcePanel);
            app.FrequencyrangeEditFieldLabel.Text = 'Frequency range (Hz)::';
            app.fromEditFieldLabel = uilabel(app.SourcePanel);
            app.fromEditFieldLabel.Text = 'from';
            app.fromEditField = uieditfield(app.SourcePanel, 'numeric');
            app.fromEditField.Value = 10;
            app.toEditFieldLabel = uilabel(app.SourcePanel);
            app.toEditFieldLabel.Text = 'to';
            app.toEditField = uieditfield(app.SourcePanel, 'numeric');
            app.toEditField.Value = 1500;
            app.TimeintervalEditFieldLabel = uilabel(app.SourcePanel);
            app.TimeintervalEditFieldLabel.Text = 'Time interval:';
            app.targettimesEditFieldLabel = uilabel(app.SourcePanel);
            app.targettimesEditFieldLabel.Text = 'target time (s)';
            app.targettimesEditField = uieditfield(app.SourcePanel, 'numeric');
            app.targettimesEditField.Value = 30;
            app.SweeptimesEditFieldLabel = uilabel(app.SourcePanel);
            app.SweeptimesEditFieldLabel.Text = 'Sweep time (s)';
            app.SweeptimesEditField = uieditfield(app.SourcePanel, 'numeric');
            app.SweeptimesEditField.Value = 30;
            app.AmplitudeVLabel = uilabel(app.SourcePanel);
            app.AmplitudeVLabel.Text = 'Amplitude (V):';
            app.AmplitudeVEditField = uieditfield(app.SourcePanel, 'numeric');
            app.AmplitudeVEditField.Limits = [0 0.05];
            app.AmplitudeVEditField.Value = 0.02;
            app.SensibilitiesPanel = uipanel(app.UIFigure);
            app.SensibilitiesPanel.Title = 'Sensibilities:';
            app.SensibilityofmicrophoneEditFieldLabel = uilabel(app.SensibilitiesPanel);
            app.SensibilityofmicrophoneEditFieldLabel.Text = 'Sensibility of microphone (V/Pa):';
            app.SensibilityofvibrometerVmsEditFieldLabel = uilabel(app.SensibilitiesPanel);
            app.SensibilityofvibrometerVmsEditFieldLabel.Text = 'Sensibility of vibrometer (V/(m/s)):';
            app.SensibilityofvibrometerVmsEditField = uieditfield(app.SensibilitiesPanel, 'numeric');
            app.SensibilityofvibrometerVmsEditField.Value = -100;
            app.Gainofmic1EditFieldLabel = uilabel(app.SensibilitiesPanel);
            app.Gainofmic1EditFieldLabel.Text = 'Gain of mic1';
            app.Gainofmic1EditField = uieditfield(app.SensibilitiesPanel, 'numeric');
            app.Gainofmic1EditField.Value = 1;
            app.NominalvalueEditFieldLabel = uilabel(app.SensibilitiesPanel);
            app.NominalvalueEditFieldLabel.Text = 'Nominal value ';
            app.NominalvalueEditField = uieditfield(app.SensibilitiesPanel, 'numeric');
            app.NominalvalueEditField.Value = -0.04;
            app.Gainofmic2EditFieldLabel = uilabel(app.SensibilitiesPanel);
            app.Gainofmic2EditFieldLabel.Text = 'Gain of mic2';
            app.Gainofmic2EditField = uieditfield(app.SensibilitiesPanel, 'numeric');
            app.Gainofmic2EditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Exp_FF_AER

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end