classdef SG__app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        TabGroup                   matlab.ui.container.TabGroup
        SetupTab                   matlab.ui.container.Tab
        SwitchMicsButtonGroup      matlab.ui.container.ButtonGroup
        NominalButton              matlab.ui.control.ToggleButton
        Button_3                   matlab.ui.control.ToggleButton
        Button_2                   matlab.ui.control.ToggleButton
        Button                     matlab.ui.control.ToggleButton
        MeasureButton              matlab.ui.control.Button
        ComputeCorrectionTFButton  matlab.ui.control.Button
        ConnectButton              matlab.ui.control.Button
        SmatrixTab                 matlab.ui.container.Tab
        MeasureButton_2            matlab.ui.control.Button
        LoadTypeButtonGroup        matlab.ui.container.ButtonGroup
        bButton                    matlab.ui.control.ToggleButton
        aButton                    matlab.ui.control.ToggleButton
        ABORTButton                matlab.ui.control.Button
        RightPanel                 matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        Lamp                       matlab.ui.control.Lamp
        EditField                  matlab.ui.control.EditField
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            addpath('__fun');
            p = param_struct(); % is a structure with all of the parameters
            tg = slrealtime(p.tg_model);
            
            %% PLOT FIRST CURVE
            cla(app.UIAxes);
            try
                load('C:/Speedgoat/temp/C12.mat');
                   plot(app.UIAxes,p.freq,C12);
            catch 
                pause(0.01);
            end
             
            %% CHECK IF CONNECTED
            try
                   app.Lamp.Enable = 'off';
                   tg.connect;
                   pause(0.01);
                   tg.isConnected == 1; %#ok<EQEFF> 
                   pause(0.01);
                   app.EditField.Value = "Speedgoat connected."; 
                   pause(0.01);
                   app.Lamp.Enable = 'on';
                   pause(0.01);
                   
            catch 
                   app.EditField.Value = "Speedgoat disconnected.";
                   pause(0.01)
            end
        end

        % Button pushed function: MeasureButton
        function MeasureButtonPushed(app, event)
            %%% DEACTIVATE BUTTONS
            set(app.MeasureButton,'Enable','off')
            set(app.ComputeCorrectionTFButton,'Enable','off')

            addpath('__fun');
            p = param_struct(); % is a structure with all of the parameter
            data = SG__measure();
                
            app.EditField.Value = "Measurement in progress..."; %%% VERBOSE


            if isfolder('C:/Speedgoat/temp') == 0
                mkdir C:/Speedgoat/temp %%% saves temp data on the local drive (for speed)
            end

            app.EditField.Value = "Computing Coherence..."; pause(0.01);
            if app.Button.Value
                %app.EditField.Value = "Computing Transfer function..."; pause(0.01);

                %%% COMPUTE TRANSFER FUNCTION
                %H12 = [];
                %[H12,F] = tfestimate(data(:,2),data(:,1),p.wind,[],p.freq,p.fs_acq);% zero padding?

                C12 = [];
                tic;
                [C12,F] = mscohere(data(:,2),data(:,1),p.wind,[],p.freq,p.fs_acq);
                delta_t = toc; %%% delta_t is displayed at end

                %%% SAVE DATA
                %app.EditField.Value = "Saving data to file..."; pause(0.01);
                data_12 = [data(:,1),data(:,2)];
                save( 'C:/Speedgoat/temp/data_12.mat','data_12');%raw data
                %save( 'C:/Speedgoat/temp/H12.mat','H12');%estimated transfer function
                save( 'C:/Speedgoat/temp/C12.mat','C12');%estimated coherence function              
                plot(app.UIAxes,F,abs(C12))

                
            elseif app.Button_2.Value
                %app.EditField.Value = "Computing Transfer function..."; pause(0.01);

                %H13 = [];
                %[H13,F] = tfestimate(data(:,3),data(:,1),p.wind,[],p.freq,p.fs_acq);
                %%% COMPUTE COHERENCE FUNCTION
                C13 = [];
                tic;
                [C13,F] = mscohere(data(:,3),data(:,1),p.wind,[],p.freq,p.fs_acq);
                delta_t = toc; %%% delta_t is displayed at end

                %%% SAVE DATA
                app.EditField.Value = "Saving data to file..."; pause(0.01);
                data_13 = [data(:,1),data(:,3)];
                save( 'C:/Speedgoat/temp/data_13.mat','data_13');%raw data
                save( 'C:/Speedgoat/temp/C13.mat','C13');%estimated coherence function              
                plot(app.UIAxes,F,abs(C13))

            elseif app.Button_3.Value 
                %app.EditField.Value = "Computing Transfer function...";  pause(0.01);
                
                %tic
                %H14 = [];
                %[H14,F] = tfestimate(data(:,4),data(:,1),p.wind,[],p.freq,p.fs_acq);
                %toc
                
                %%% COMPUTE COHERENCE FUNCTION
                C14 = [];
                tic;
                [C14,F] = mscohere(data(:,4),data(:,1),p.wind,[],p.freq,p.fs_acq);
                delta_t = toc; %%% delta_t is displayed at end

                %%% SAVE DATA
                app.EditField.Value = "Saving data to file..."; pause(0.01);
                data_14 = [data(:,1),data(:,4)];
                save( 'C:/Speedgoat/temp/data_14.mat','data_14');%raw data
                save( 'C:/Speedgoat/temp/C14.mat','C14');%estimated coherence function              
                plot(app.UIAxes,F,abs(C14))

            elseif app.NominalButton.Value 
                %app.EditField.Value = "Computing Transfer function...";  pause(0.01);
                
                %tic
                %H21 = [];
                %H31 = [];
                %H41 = [];
                %
                %[H21,F] = tfestimate(data(:,1),data(:,2),p.wind,[],p.freq,p.fs_acq); %%%% INVERSE???? no - this is NOT the cross signal as in the pulse see 20220701__MEASUREMENTS
                %[H31,F] = tfestimate(data(:,1),data(:,3),p.wind,[],p.freq,p.fs_acq);
                %[H41,F] = tfestimate(data(:,1),data(:,4),p.wind,[],p.freq,p.fs_acq);
                %toc 
                %%% COMPUTE COHERENCE FUNCTION
                tic
                C21 = [];
                C31 = [];
                C41 = [];

                [C21,F] = mscohere(data(:,1),data(:,2),p.wind,[],p.freq,p.fs_acq); 
                [C31,F] = mscohere(data(:,1),data(:,3),p.wind,[],p.freq,p.fs_acq);
                [C41,F] = mscohere(data(:,1),data(:,4),p.wind,[],p.freq,p.fs_acq);
                delta_t = toc;

                data_21 = [data(:,1),data(:,2)];%%%% INVERSE???? no - this is NOT the cross signal as in the pulse see 20220701__MEASUREMENTS
                data_31 = [data(:,1),data(:,3)];
                data_41 = [data(:,1),data(:,4)];

             
                %%% SAVE DATA TO TEMP FILE
                app.EditField.Value = "Saving data to file..."; pause(0.01);

                save( 'C:/Speedgoat/temp/data_21.mat','data_21');
                save( 'C:/Speedgoat/temp/data_31.mat','data_31');
                save( 'C:/Speedgoat/temp/data_41.mat','data_41');

                save( 'C:/Speedgoat/temp/C21.mat','C21');%estimated coherence function  
                save( 'C:/Speedgoat/temp/C31.mat','C31'); 
                save( 'C:/Speedgoat/temp/C41.mat','C41'); 
                
                
                plot(app.UIAxes,F,abs(C21));
                hold(app.UIAxes,'on')
                plot(app.UIAxes,F,abs(C31));
                plot(app.UIAxes,F,abs(C41));
                hold(app.UIAxes,'off');

            end
         
         
        app.EditField.Value =  "Data saved."; pause(0.5); %%% VERBOSE
        app.EditField.Value = strcat("Computation time = ",string(delta_t )," s"); pause(0.01);
        %%% BUTTON ON
        set(app.MeasureButton,'Enable','on')
        set(app.ComputeCorrectionTFButton,'Enable','on')
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            addpath('__fun');
            p = param_struct(); % is a structure with all of the parameter
            tg = slrealtime(p.tg_model);
            try
                app.EditField.Value = "Connecting..."; 
                pause(0.01);
                tg.connect;

                %if tg.isConnected == 1
                app.Lamp.Enable = 'off';
                pause(0.01);
                set(app.ConnectButton,'Enable','off');
                pause(0.01);
                app.EditField.Value = "Connection estabished."; 
                pause(0.01);
                app.EditField.Value = "Building target..."; 
                pause(0.01);
                %c0=0.1; WTF
                SG__build();
                pause(0.01);
                app.EditField.Value = "System ready."; 
                app.Lamp.Enable = 'on';
                pause(0.01);
%                 else
%                     app.Lamp.Enable = 'off';
%                     app.EditField.Value = "No connection. Try again."; 
                
            catch
                app.Lamp.Enable = 'off';
                app.EditField.Value = "Connection failed."; 
            end

            set(app.ConnectButton,'Enable','on'); %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end

        % Button pushed function: ComputeCorrectionTFButton
        function ComputeCorrectionTFButtonPushed(app, event)
            set(app.ComputeCorrectionTFButton,'Enable','off') 
            addpath('__fun');
            p = param_struct(); % is a structure with all of the parameter
        
 
            %%% INPUT CALIBRATION DATA 
            try
           
                load('C:/Speedgoat/temp/data_12.mat');
                load('C:/Speedgoat/temp/data_13.mat');
                load('C:/Speedgoat/temp/data_14.mat');
               
                load('C:/Speedgoat/temp/data_21.mat');
                load('C:/Speedgoat/temp/data_31.mat');
                load('C:/Speedgoat/temp/data_41.mat');

                load('C:/Speedgoat/temp/C21.mat');
                load('C:/Speedgoat/temp/C31.mat');
                load('C:/Speedgoat/temp/C41.mat');

                    
                % this is where the transfer function is estimated.
                app.EditField.Value = "Computing transfer functions...";pause(0.01) %%% VERBOSE
                tic;
                [H12,F] = tfestimate(data_12(:,1),data_12(:,2),p.wind,[],p.freq,p.fs_acq); 
                [H13,F] = tfestimate(data_13(:,1),data_13(:,2),p.wind,[],p.freq,p.fs_acq);
                [H14,F] = tfestimate(data_14(:,1),data_14(:,2),p.wind,[],p.freq,p.fs_acq);

                [H21,F] = tfestimate(data_21(:,1),data_21(:,2),p.wind,[],p.freq,p.fs_acq);%%%the subscript "a" is added here to such that the data can be directly processed in the Smatrix tab
                [H31,F] = tfestimate(data_31(:,1),data_31(:,2),p.wind,[],p.freq,p.fs_acq);
                [H41,F] = tfestimate(data_41(:,1),data_41(:,2),p.wind,[],p.freq,p.fs_acq);

                H21_corr = sqrt(H12.*H21); %%%20221110--> changes from ./" to ".*" !!!!
                H31_corr = sqrt(H13.*H31);
                H41_corr = sqrt(H14.*H41);
                
                %freq = p.freq;
                %vq = interp1(x,v,xq) INTERPOLATE DATA
               

                %%% SAVE TRANSFER FUNCTIONS AND CORRECTION IN LOCAL FOLDER
                
                app.EditField.Value = "Saving data...";pause(0.01) %%% VERBOSE

                if isfolder ('__data') == 0;
                    mkdir __data %%% ON SERVER
                end

                save( './__data/H21.mat','H21');
                save( './__data/H31.mat','H31');
                save( './__data/H41.mat','H41'); 

                save( './__data/H12.mat','H12');
                save( './__data/H13.mat','H13')
                save( './__data/H14.mat','H14');
                
                save( './__data/H21_corr.mat','H21_corr');
                save( './__data/H31_corr.mat','H31_corr');
                save( './__data/H41_corr.mat','H41_corr');
                
                save( './__data/data_21.mat','data_21');
                save( './__data/data_31.mat','data_31');
                save( './__data/data_41.mat','data_41');

                %save( './__data/freq.mat','freq');


                fprintf('2\n')
                %%% SAVE WITH SUBSCRIPT "a"
                % added here to such that the data can be directly processed in the Smatrix tab
               
                H21_a = H21;
                H31_a = H31;
                H41_a = H41;

                C21_a = C21;
                C31_a = C31;
                C41_a = C41;

                data_21_a = data_21;
                data_31_a = data_31;
                data_41_a = data_41;

%                 save( './__data/H21_a.mat','H21_a');
%                 save( './__data/H31_a.mat','H31_a');
%                 save( './__data/H41_a.mat','H41_a'); 
% 
%                 save( './__data/C21_a.mat','C21_a');
%                 save( './__data/C31_a.mat','C31_a');
%                 save( './__data/C41_a.mat','C41_a'); 
% 
%                 save( './__data/data_21_a.mat','data_21_a');
%                 save( './__data/data_31_a.mat','data_31_a');
%                 save( './__data/data_41_a.mat','data_41_a');

                save( 'C:/Speedgoat/temp/H21_a.mat','H21_a');
                save( 'C:/Speedgoat/temp/H31_a.mat','H31_a');
                save( 'C:/Speedgoat/temp/H41_a.mat','H41_a'); 

                save( 'C:/Speedgoat/temp/C21_a.mat','C21_a');
                save( 'C:/Speedgoat/temp/C31_a.mat','C31_a');
                save( 'C:/Speedgoat/temp/C41_a.mat','C41_a'); 

                save( 'C:/Speedgoat/temp/data_21_a.mat','data_21_a');
                save( 'C:/Speedgoat/temp/data_31_a.mat','data_31_a');
                save( 'C:/Speedgoat/temp/data_41_a.mat','data_41_a');

                delta_t = toc; %%% delta_t is displayed at end

                app.EditField.Value = "Estimated transfer functions and calibration data saved."; %%% VERBOSE
                pause(0.5);
                app.EditField.Value = strcat("Computation time = ",string(delta_t )," s"); pause(0.01);
            catch
                app.EditField.Value = " "; 
                f = errordlg('Data file(s) not found.','File Error');
            end
            
            set(app.ComputeCorrectionTFButton,'Enable','on')  ; 

        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end

        % Selection changed function: SwitchMicsButtonGroup
        function TranferFunctionButtonGroupSelectionChanged(app, event)
            %%% 
            addpath('__fun');
            p = param_struct(); % is a structure with all of the parameter

            try 
                app.EditField.Value = "Plotting..."
                pause(0.01)
                cla(app.UIAxes);

                if app.Button.Value
                    load('C:/Speedgoat/temp/C12.mat')
                    plot(app.UIAxes,p.freq,C12);
                    
                elseif app.Button_2.Value
                    load('C:/Speedgoat/temp/C13.mat')
                    plot(app.UIAxes,p.freq,C13);
                    
                elseif app.Button_3.Value
                    load('C:/Speedgoat/temp/C14.mat')
                    plot(app.UIAxes,p.freq,C14);
                    
                elseif app.NominalButton.Value
                    load('C:/Speedgoat/temp/C21.mat')
                    load('C:/Speedgoat/temp/C31.mat')
                    load('C:/Speedgoat/temp/C41.mat')
                    
                    plot(app.UIAxes,p.freq,C21);
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,p.freq,C31);
                    plot(app.UIAxes,p.freq,C41);
                    hold(app.UIAxes,'off');
                end
                app.EditField.Value = ""
            catch
                app.EditField.Value = "Calibration file(s) not found."

            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {479, 479};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: ABORTButton
        function ABORTButtonPushed(app, event)
            addpath('__fun');
            p = param_struct(); % is a structure with all of the parameter
            tg = slrealtime(p.tg_model);
            
            %sc_list = tg.getscope; % all the scopes
            %sc = sc_list([sc_list.ScopeId] == 2); % scope 2 record
            
            tg.stop;% stops target
            
    
            pause(0.01);
            app.EditField.Value =  "Measurement aborted." %%% VERBOSE
            pause(0.01);
                
            %%% BUTTON ON
            set(app.ConnectButton,'Enable','on')
            set(app.MeasureButton,'Enable','on')
            set(app.MeasureButton_2,'Enable','on')
            set(app.ComputeCorrectionTFButton,'Enable','on')
        end

        % Callback function
        function SwitchValueChanged(app, event)
           
        end

        % Selection changed function: LoadTypeButtonGroup
        function LoadTypeButtonGroupSelectionChanged(app, event)
            addpath('__fun');
            p = param_struct(); % is a structure with all of the parameter
            
            try 
                app.EditField.Value = "Plotting..."
                pause(0.01)
                cla(app.UIAxes);
                if app.aButton.Value
                    load('C:/Speedgoat/temp/C21_a.mat')
                    load('C:/Speedgoat/temp/C31_a.mat')
                    load('C:/Speedgoat/temp/C41_a.mat')
                    
                    plot(app.UIAxes,p.freq,abs(C21_a));
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,p.freq,abs(C31_a));
                    plot(app.UIAxes,p.freq,abs(C41_a));
                    hold(app.UIAxes,'off');
                    
                elseif app.bButton.Value
                    load('C:/Speedgoat/temp/C21_b.mat')
                    load('C:/Speedgoat/temp/C31_b.mat')
                    load('C:/Speedgoat/temp/C41_b.mat')
                    plot(app.UIAxes,p.freq,abs(C21_b));
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,p.freq,abs(C31_b));
                    plot(app.UIAxes,p.freq,abs(C41_b));
                    hold(app.UIAxes,'off');
                    
                end
                app.EditField.Value = ""
            catch
                app.EditField.Value = "Calibration file(s) not found."

            end
            
        end

        % Button pushed function: MeasureButton_2
        function MeasureButton_2Pushed(app, event)
            selectedButton = app.LoadTypeButtonGroup.SelectedObject;
            try
                if isfolder('__data') == 0;
                    mkdir __data;
                end
                p = param_struct(); % is a structure with all of the parameter
                
              
                 %%% BUTTON OFF
                set(app.MeasureButton_2,'Enable','off')
             
                if selectedButton == app.aButton
                    if isfile("C:/Speedgoat/temp/H21_a.mat") 
                        answer = questdlg('Data files are already loaded. Would you like to overwrite them?','Warning','Yes','Cancel','Cancel');
                        % Handle response
                        switch answer
                            case 'Yes'
                                %%%
                            case 'Cancel'
                                set(app.MeasureButton_2,'Enable','on'); pause(0.01);
                                return
                        end
                    end
                    
                    app.EditField.Value = "Measuring...";  pause(0.01);
                    data = SG__measure();
            
                    app.EditField.Value = "Computing coherence and transfer function...";  pause(0.01);
    
                    H21_a = [];               
                    H31_a = [];
                    H41_a = [];
                    
                    C21_a = [];% coherence 
                    C31_a = [];% coherence
                    C41_a = [];% coherence  
    
                    [H21_a,F] = tfestimate(data(:,1),data(:,2),p.wind,[],p.freq,p.fs_acq);
                    [H31_a,F] = tfestimate(data(:,1),data(:,3),p.wind,[],p.freq,p.fs_acq);
                    [H41_a,F] = tfestimate(data(:,1),data(:,4),p.wind,[],p.freq,p.fs_acq);
    
                    [C21_a,F] = mscohere(data(:,1),data(:,2),p.wind,[],p.freq,p.fs_acq);
                    [C31_a,F] = mscohere(data(:,1),data(:,3),p.wind,[],p.freq,p.fs_acq);
                    [C41_a,F] = mscohere(data(:,1),data(:,4),p.wind,[],p.freq,p.fs_acq);
                    
                    save( 'C:/Speedgoat/temp/H21_a.mat','H21_a');
                    save( 'C:/Speedgoat/temp/H31_a.mat','H31_a');
                    save( 'C:/Speedgoat/temp/H41_a.mat','H41_a');
    
                    save( 'C:/Speedgoat/temp/C21_a.mat','C21_a');
                    save( 'C:/Speedgoat/temp/C31_a.mat','C31_a');
                    save( 'C:/Speedgoat/temp/C41_a.mat','C41_a');
                    
                    plot(app.UIAxes,F,abs(C21_a));
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,F,abs(C31_a));
                    plot(app.UIAxes,F,abs(C41_a));
                    hold(app.UIAxes,'off');


                else          
                    if isfile("./__data/H21_b.mat")
                        answer = questdlg('data files are already loaded. Would you like to overwrite them?','Warning','Yes','Cancel','Cancel');
                        % Handle response
                        switch answer
                            case 'Yes'
                                %%%
                            case 'Cancel'
                                set(app.MeasureButton_2,'Enable','on'); pause(0.01);
                                return
                        end
                    end
    
                    app.EditField.Value = "Measuring...";  pause(0.01);
                    data = SG__measure();
    
                    app.EditField.Value = "Computing Transfer function...";  pause(0.01);
    
                    H21_b = [];
                    H31_b = [];
                    H41_b = [];
    
                    C21_b = [];% coherence 
                    C31_b = [];% coherence
                    C41_b = [];% coherence  
                    
                    [H21_b,F] = tfestimate(data(:,1),data(:,2),p.wind,[],p.freq,p.fs_acq);
                    [H31_b,F] = tfestimate(data(:,1),data(:,3),p.wind,[],p.freq,p.fs_acq);
                    [H41_b,F] = tfestimate(data(:,1),data(:,4),p.wind,[],p.freq,p.fs_acq);    
    
                    [C21_b,F] = mscohere(data(:,1),data(:,2),p.wind,[] ,p.freq,p.fs_acq);
                    [C31_b,F] = mscohere(data(:,1),data(:,3),p.wind,[],p.freq,p.fs_acq);
                    [C41_b,F] = mscohere(data(:,1),data(:,4),p.wind,[],p.freq,p.fs_acq);
                    
                    save( 'C:/Speedgoat/temp/H21_b.mat','H21_b');
                    save( 'C:/Speedgoat/temp/H31_b.mat','H31_b');
                    save( 'C:/Speedgoat/temp/H41_b.mat','H41_b');
    
                    save( 'C:/Speedgoat/temp/C21_b.mat','C21_b');
                    save( 'C:/Speedgoat/temp/C31_b.mat','C31_b');
                    save( 'C:/Speedgoat/temp/C41_b.mat','C41_b');
                    
    
                    plot(app.UIAxes,F,abs(C21_b));
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,F,abs(C31_b));
                    plot(app.UIAxes,F,abs(C41_b));
                    hold(app.UIAxes,'off');
    
                end
    
                app.EditField.Value = ""; pause(0.01);
                %%% BUTTON ON
                set(app.MeasureButton_2,'Enable','on'); pause(0.01);
            end

        end

        % Callback function
        function TransferFunction3ButtonGroupSelectionChanged(app, event)
            selectedButton = app.SwitchMicsButtonGroup.SelectedObject;
            
        end

        % Size changed function: SwitchMicsButtonGroup
        function SwitchMicsButtonGroupSizeChanged(app, event)
            position = app.SwitchMicsButtonGroup.Position;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ABORTButton = uibutton(app.LeftPanel, 'push');
            app.ABORTButton.ButtonPushedFcn = createCallbackFcn(app, @ABORTButtonPushed, true);
            app.ABORTButton.Text = 'ABORT';
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.ConnectButton = uibutton(app.SetupTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect ';
            app.ComputeCorrectionTFButton = uibutton(app.SetupTab, 'push');
            app.ComputeCorrectionTFButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeCorrectionTFButtonPushed, true);
            app.ComputeCorrectionTFButton.Text = 'Compute Correction TF';
            app.MeasureButton = uibutton(app.SetupTab, 'push');
            app.MeasureButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureButtonPushed, true);
            app.MeasureButton.Text = 'Measure';
            app.SwitchMicsButtonGroup = uibuttongroup(app.SetupTab);
            app.SwitchMicsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TranferFunctionButtonGroupSelectionChanged, true);
            app.SwitchMicsButtonGroup.Title = 'Switch Mics.';
            app.SwitchMicsButtonGroup.SizeChangedFcn = createCallbackFcn(app, @SwitchMicsButtonGroupSizeChanged, true);
            app.Button = uitogglebutton(app.SwitchMicsButtonGroup);
            app.Button.Text = '1 & 2';
            app.Button.Value = true;
            app.Button_2 = uitogglebutton(app.SwitchMicsButtonGroup);
            app.Button_2.Text = '1 & 3';
            app.Button_3 = uitogglebutton(app.SwitchMicsButtonGroup);
            app.Button_3.Text = '1 & 4';
            app.NominalButton = uitogglebutton(app.SwitchMicsButtonGroup);
            app.NominalButton.Text = 'Nominal';
            app.SmatrixTab = uitab(app.TabGroup);
            app.SmatrixTab.Title = 'S-matrix';
            app.LoadTypeButtonGroup = uibuttongroup(app.SmatrixTab);
            app.LoadTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LoadTypeButtonGroupSelectionChanged, true);
            app.LoadTypeButtonGroup.Title = 'Load Type';
            app.aButton = uitogglebutton(app.LoadTypeButtonGroup);
            app.aButton.Text = 'a';
            app.aButton.Value = true;
            app.bButton = uitogglebutton(app.LoadTypeButtonGroup);
            app.bButton.Text = 'b';
            app.MeasureButton_2 = uibutton(app.SmatrixTab, 'push');
            app.MeasureButton_2.ButtonPushedFcn = createCallbackFcn(app, @MeasureButton_2Pushed, true);
            app.MeasureButton_2.Text = 'Measure';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Coherence')
            xlabel(app.UIAxes, 'Freq (Hz)')
            ylabel(app.UIAxes, 'a.u.')
            app.EditField = uieditfield(app.GridLayout2, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Editable = 'off';
            app.Lamp = uilamp(app.GridLayout2);
            app.Lamp.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SG__app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end